classdef Minesweeper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        ChoosedifficultyDropDown       matlab.ui.control.DropDown
        ChoosedifficultyDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Minefield double
        Count double % Description
        MinefieldTable matlab.ui.control.Table
    end

    methods (Access = public)


        function generateBomb(app, minefield, difficulty)
            % Get window size
            windowSize = app.UIFigure.Position;
            [numRows, numCols] = size(minefield);
           
            % Initialize bomb count
            app.Count = 0;
            
            % Place bombs based on difficulty
            for k = 1:difficulty
                randRow = randi([1, numRows], 1);
                randCol = randi([1, numCols], 1);
                if minefield(randRow, randCol) == 0
                    minefield(randRow, randCol) = 50;
                    app.Count = app.Count + 1;
                end
            end
            
            % Update minefield with neighboring mine counts
            minefield = countNeighboringMines(app, minefield, numRows, numCols);
            

            [tableWidth, tableHeight, fontSize] = setTableDimensions(app,difficulty, windowSize);
            % Calculate cell width
            cellWidth = tableWidth / numCols;
            
            % Set table position based on window size and calculated dimensions
            tablePosition = [
    (windowSize(3) - tableWidth)/100, ...
    (windowSize(4) - tableHeight) , ...
    tableWidth, ...
    tableHeight
];
            
            % Set column widths based on evenly distributed cell width
      
            
            % Create and configure the uitable
            app.MinefieldTable = uitable(app.UIFigure, ...
    'Data', minefield, 'FontSize', fontSize, ...
    'ColumnWidth', num2cell(repmat(cellWidth, 1, numCols)), ...
    'Position', tablePosition, ...
                'BackgroundColor', [0.8 0.8 0.8; 0.8 0.8 0.8], ...
                'ForegroundColor', [0 0 0], ...
                'ColumnName', {}, 'RowName', {}, ...
                'CellEditCallback', @(src, event) updateCellColors(app, src, event), ...
                'CellSelectionCallback', @(src, event) cellSelected(app, src, event));

    % Add bomb icon style to bomb cells
    for m = 1:numRows
        for n = 1:numCols
            if minefield(m,n) == 50
                    
                s = uistyle(Icon="C:\Users\Administrator\Desktop\Minesweeper\images\bomb.png");
                addStyle(app.MinefieldTable, s, "cell", [m, n]);
            end
        end
    end
end

        %function updateCellColors(~, ~, event)
            % row = event.Indices(1);
            % col = event.Indices(2);
            % disp(app.MinefieldTable.Data(row,col));

            % if data(row, col) == 1
            %     src.RowStriping = 'off';
            %     src.Data(row, col) = 'X';
            %     src.BackgroundColor(row, col) = [1 0 0];
            %     src.ForegroundColor(row, col) = [1 1 1];
            % end
        %     else
        %         count = countNeighboringMines(app, data, row, col);
        %         if count > 0
        %             src.Data{row, col} = count;
        %             src.BackgroundColor{row, col} = [0.8 0.8 0.8];
        %             src.ForegroundColor{row, col} = [0 0 1];
        %         else
        %             src.Data{row, col} = '';
        %             src.BackgroundColor{row, col} = [0.8 0.8 0.8];
        %             src.ForegroundColor{row, col} = [0 0 0];
        %         end
        %     end
         %end
        
        
         function cellSelected(app, ~, event)
            row = event.Indices(1);
            col = event.Indices(2);
            if app.MinefieldTable.Data(row,col) == 50
                app.MinefieldTable.RowStriping = 'off';
                app.MinefieldTable.BackgroundColor = [1 0 0; 1 0 0];
                app.MinefieldTable.ForegroundColor = [1 1 1];
                app.MinefieldTable.CellSelectionCallback = '';
                %app.Restart = uibutton(app.Minefield, "Text","restart", "ButtonPushedFcn",StartGame(app,event),"Position", [200 100 100 22]);
                % pause(5);
                % app.UIFigure.Visible = 'on';
            else
            app.MinefieldTable.Data(row,col) = countNeighboringMines(app,app.MinefieldTable.Data,row,col);
            end
            % Handle cell selection as needed
            % disp(['Cell Selected: Row ', num2str(row), ', Column ', num2str(col)]);
            
        end
        function field = countNeighboringMines(~,data, row, col)
            for i=1:row
                for j=1:col
                    if (data(i,j) ~= 50)
                        if(i == 1 && j==1)
                            data(i,j) = (data(i+1,j) == 50) + (data(i+1,j+1) == 50) + (data(i, j+1) == 50);
                        elseif(i==row && j==1)
                            data(i,j) = (data(i-1,j) == 50) + (data(i-1,j+1) == 50) + (data(i, j+1) == 50);
                        elseif(i==row && j==col)
                            data(i,j) = (data(i-1,j) == 50) + (data(i-1,j-1) == 50) + (data(i, j-1) == 50);
                        elseif(i==1 && j==col)
                            data(i,j) = (data(i+1,j) == 50) + (data(i+1,j-1) == 50) + (data(i, j-1) == 50);
                        elseif(i==1)
                            data(i,j) = (data(i+1,j) == 50) + (data(i+1,j+1) == 50) + (data(i, j+1) == 50) + (data(i, j-1) == 50) + (data(i+1,j-1) == 50);
                        elseif(i==row)
                            data(i,j) = (data(i-1,j) == 50) + (data(i-1,j+1) == 50) + (data(i, j+1) == 50) + (data(i, j-1) == 50) + (data(i-1,j-1) == 50);
                        elseif(j==1)
                            data(i,j) = (data(i+1,j) == 50) + (data(i+1,j+1) == 50) + (data(i, j+1) == 50) + (data(i-1, j) == 50) + (data(i-1,j+1) == 50);
                        elseif(j==col)
                            data(i,j) = (data(i+1,j) == 50) + (data(i+1,j-1) == 50) + (data(i, j-1) == 50) + (data(i-1, j-1) == 50) + (data(i-1,j) == 50);
                        else
                            data(i,j) = (data(i+1,j) == 50) + (data(i+1,j-1) == 50) + (data(i, j-1) == 50) + (data(i-1, j-1) == 50) + (data(i-1,j) == 50) + (data(i-1,j+1) == 50) + (data(i, j+1) == 50) + (data(i+1,j+1) == 50);
                        end
                    end
                end
            end
            field = data;
        end
        function [tableWidth, tableHeight, fontSize] = setTableDimensions(~,difficulty, windowSize)
                switch difficulty
                   
                        
                    case 40
                        tableWidth = windowSize(3) * 0.7;
                        tableHeight = windowSize(4) ;
                        fontSize = 20;
                    case 99
                        tableWidth = windowSize(3)*0.7 ;
                        tableHeight = windowSize(4);
                        fontSize = 15;
                    otherwise
                        tableWidth = windowSize(3) *0.7;
                        tableHeight = windowSize(4) ;
                        fontSize = 30;
                end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartGame(app, event)
            difficulty=app.ChoosedifficultyDropDown.Value;
            switch difficulty
                case 'Medium'
                    field=zeros(16);
                    generateBomb(app,field,40);
                case 'Hard'
                    field=zeros(16,30);
                    generateBomb(app,field,99);
                otherwise
                    field=zeros(8);
                    generateBomb(app,field,10);
            
            end
            
            %app.UIFigure.Visible='off';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'C:\Users\Administrator\Desktop\Minesweeper\images\bomb.png';
            app.ChoosedifficultyDropDownLabel = uilabel(app.UIFigure);
            app.ChoosedifficultyDropDownLabel.Text = 'Choose difficulty';
            app.ChoosedifficultyDropDown = uidropdown(app.UIFigure);
            app.ChoosedifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.ChoosedifficultyDropDown.Placeholder = '...';
            app.ChoosedifficultyDropDown.Value = 'Easy';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minesweeper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end